classdef resultView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        MVPALogo                        matlab.ui.control.Image
        versionLabel                    matlab.ui.control.Label
        MVPAlabToolboxLabel             matlab.ui.control.Label
        resultFilePanel                 matlab.ui.container.Panel
        LoadresultfileButton            matlab.ui.control.Button
        EditFieldLabel                  matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        plotResutsButton                matlab.ui.control.Button
        ChangecolorschemeDropDownLabel  matlab.ui.control.Label
        colorSchemeSelector             matlab.ui.control.DropDown
        StatisticsPanel                 matlab.ui.container.Panel
        HighlightsignificantclustersCheckBox  matlab.ui.control.CheckBox
        ConfigurationPanel              matlab.ui.container.Panel
    end

    
    properties (Access = private)
        result % Description
        stats % Description
        graph % Description
        cfg
    end
    
    methods (Access = private)
        
        function updateGUI(app)
            
            % 
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.graph = mvpalab_plotinit();
            app.updateGUI();
            dirtoload = '/Users/David/Desktop/mvpa/';
            temp = load([dirtoload filesep 'cfg.mat']);
            app.cfg = temp.cfg;
            temp = load([dirtoload filesep 'results' filesep 'result.mat']);
            app.result = temp.result;
            temp = load([dirtoload filesep 'stats' filesep 'auc' filesep 'stats.mat']);
            app.stats = temp.stats;
            
        end

        % Button pushed function: plotResutsButton
        function plotResutsButtonPushed(app, event)
            mvpalab_plottg(app.graph,app.cfg,app.result.cr,app.stats);
        end

        % Value changed function: colorSchemeSelector
        function colorSchemeSelectorValueChanged(app, event)
            app.graph.colorMap = getfield(app.graph.grads,...
                app.colorSchemeSelector.Value);
            colormap(app.graph.colorMap);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.MVPALogo = uiimage(app.Panel);
            app.MVPALogo.ImageSource = 'mvpalab.png';
            app.versionLabel = uilabel(app.Panel);
            app.versionLabel.Text = 'BETA v0.1';
            app.MVPAlabToolboxLabel = uilabel(app.Panel);
            app.MVPAlabToolboxLabel.Text = 'MVPAlab Toolbox';
            app.resultFilePanel = uipanel(app.UIFigure);
            app.resultFilePanel.Title = 'Result file location:';
            app.LoadresultfileButton = uibutton(app.resultFilePanel, 'push');
            app.LoadresultfileButton.Text = 'Load result file';
            app.EditFieldLabel = uilabel(app.resultFilePanel);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.resultFilePanel, 'numeric');
            app.plotResutsButton = uibutton(app.UIFigure, 'push');
            app.plotResutsButton.ButtonPushedFcn = createCallbackFcn(app, @plotResutsButtonPushed, true);
            app.plotResutsButton.Text = 'Plot results';
            app.ChangecolorschemeDropDownLabel = uilabel(app.UIFigure);
            app.ChangecolorschemeDropDownLabel.Text = 'Change color scheme';
            app.colorSchemeSelector = uidropdown(app.UIFigure);
            app.colorSchemeSelector.Items = {'MVPAlab', 'Sun', 'Mercury', 'Venus', 'Earth', 'Mars', 'Jupiter', 'Saturn', 'Uranus', 'Neptune'};
            app.colorSchemeSelector.ItemsData = {'mvpalab', 'sun', 'mercury', 'venus', 'earth', 'mars', 'jupiter', 'saturn', 'uranus', 'neptune', ''};
            app.colorSchemeSelector.ValueChangedFcn = createCallbackFcn(app, @colorSchemeSelectorValueChanged, true);
            app.colorSchemeSelector.Value = 'mvpalab';
            app.StatisticsPanel = uipanel(app.UIFigure);
            app.StatisticsPanel.Title = 'Statistics:';
            app.HighlightsignificantclustersCheckBox = uicheckbox(app.StatisticsPanel);
            app.HighlightsignificantclustersCheckBox.Text = 'Highlight significant clusters';
            app.ConfigurationPanel = uipanel(app.UIFigure);
            app.ConfigurationPanel.Title = 'Configuration:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = resultView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end